classdef Receiver < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        CardErrorLabel              matlab.ui.control.Label
        SamplingRate                matlab.ui.control.NumericEditField
        SamplingRateEditFieldLabel  matlab.ui.control.Label
        RealtimeSeconds             matlab.ui.control.NumericEditField
        EnterSecondsLabel_2         matlab.ui.control.Label
        DecodeRealtimeButton        matlab.ui.control.Button
        CRCLabel                    matlab.ui.control.Label
        ICREFERENCELabel            matlab.ui.control.Label
        MEMORYLabel                 matlab.ui.control.Label
        AFILabel                    matlab.ui.control.Label
        DSFIDLabel                  matlab.ui.control.Label
        UIDLabel                    matlab.ui.control.Label
        INFOFLAGSLabel              matlab.ui.control.Label
        FLAGSLabel                  matlab.ui.control.Label
        DecodeFileDataButton        matlab.ui.control.Button
        SelectFileDropDown          matlab.ui.control.DropDown
        SelectFileLabel             matlab.ui.control.Label
        ReadFileButton              matlab.ui.control.Button
        WriterButton                matlab.ui.control.Button
        SecondsField                matlab.ui.control.NumericEditField
        EnterSecondsLabel           matlab.ui.control.Label
        FileName                    matlab.ui.control.EditField
        EnterFileNameLabel          matlab.ui.control.Label
        Status                      matlab.ui.control.Label
        StatusLabel                 matlab.ui.control.Label
        RetryConnectionButton       matlab.ui.control.Button
        ConnectingStatus            matlab.ui.control.Label
        ConnectionStatusLabel       matlab.ui.control.Label
        RealtimeButton              matlab.ui.control.StateButton
    end


    methods (Access = private)

        %Connects to USRP Receiver and tests the connection
        function rx = establishConnection(app)
            %Parameters
            center_frequency = 13560000;
            sampling_rate = app.SamplingRate.Value;
            decimation_factor = round(100000000/sampling_rate);

            %Updates Connection Status
            app.ConnectingStatus.Visible = true;
            app.ConnectingStatus.Text = 'Connecting...';
            app.ConnectingStatus.FontColor = [1,1,0];
            drawnow();

            %Connects to USRP Receiver
            rx = comm.SDRuReceiver(...
                Platform = "N200/N210/USRP2", ...
                IPAddress = '192.168.10.2', ...
                OutputDataType = "double", ...
                DecimationFactor = decimation_factor, ...
                Gain = 0, ...
                CenterFrequency = center_frequency, ...
                SamplesPerFrame = 50000);

            %Tests the USRP connection
            try
                rx();
            catch
                app.ConnectingStatus.Text = 'Connection Failed';
                app.ConnectingStatus.FontColor = [1,0,0];
                app.RetryConnectionButton.Enable = true;
                release(rx);
                return;
            end

            %Updates the UI
            app.DecodeRealtimeButton.Enable = true;
            app.RealtimeButton.Enable = true;
            app.WriterButton.Enable = true;
            app.ConnectingStatus.Text = "Connection Established";
            app.ConnectingStatus.FontColor = [0,1,0];
            release(rx);
        end
        
        %Receives and saves the USRP receiver data for given seconds
        function writerReception(app)
            %Starting Parameters
            center_frequency = 13560000;
            sampling_rate = app.SamplingRate.Value;

            %Gets global variables
            global rx;
            global time_scope;

            %Releases variables
            release(time_scope);
            release(rx);
            time_scope.TimeSpanSource = 'property'; %Sets X-Axis to timespan

            %Gets the path and name of file to save
            file_name = app.FileName.Value;
            file_path = append('C:\Users\akamrath2\Documents\USRP_Research_Summer2023\Applications\BasebandFiles\', file_name, '.bb');

            %Create the file writer object
            rxWriter = comm.BasebandFileWriter(file_path, ...
                SampleRate=sampling_rate, ...
                CenterFrequency=center_frequency);

            show(time_scope);

            %Gets the time of one frame of data
            frame_duration = (rx.SamplesPerFrame)/(sampling_rate);
            time = 0;

            %Gets the first frame of data to properly update the labels
            data = rx();
            rxWriter(data);
            time_scope(data);
            time = time + frame_duration;
            app.WriterButton.Text = "Receiving for Writer...";
            app.WriterButton.FontColor = [0,1,0];
            app.WriterButton.Enable = false;
            app.Status.Text = "Receiving";
            app.Status.FontColor = [0,1,0];
            drawnow();

            %Writes data to baseband file for given num of seconds
            while time < app.SecondsField.Value
                data = rx();
                rxWriter(data);
                time_scope(data);
                time = time + frame_duration;
            end

            %Releases
            release(rxWriter);
            release(time_scope);
            release(rx);
        end

        %Receives and plots the USRP Receiver data in realtime
        function realtimeReception(app)
            %Gets global variables
            global rx;
            global time_scope;

            %Releases global variables before use
            release(time_scope);
            release(rx);

            time_scope.TimeSpanSource = 'property'; %Sets X-Axis to timespan
            show(time_scope);
            
            %Gets the first frame of data to properly update the labels
            data = rx(); %Gets one frame of data
            time_scope(data); %Shows data in time scope
            app.RealtimeButton.Text = "Receiving in Realtime...";
            app.RealtimeButton.FontColor = [0,1,0];
            app.Status.Text = "Receiving";
            app.Status.FontColor = [0,1,0];
            drawnow();

            %Gets receiver data while Realtime reception button is toggled
            while app.RealtimeButton.Value == 1
                data = rx(); %Gets one frame of data
                time_scope(data); %Shows data in time scope
                drawnow(); %Updates the button value
            end

            %Releases
            release(time_scope);
            release(rx);
        end

        function readFile(app)
            %Gets and releases time scope
            global time_scope
            release(time_scope);
            
            %Sets time scope to show full file
            time_scope.TimeSpanSource = 'auto';
            show(time_scope);
            
            %Updates UI
            app.ReadFileButton.Text = 'Loading...';
            app.ReadFileButton.FontColor = [0,1,0];
            app.ReadFileButton.Enable = false;
            app.Status.Text = 'Loading...';
            app.Status.FontColor = [0,1,0];
            drawnow();

            %Gets path of selected file
            file_name = app.SelectFileDropDown.Value;
            file_path = append('C:\Users\akamrath2\Documents\USRP_Research_Summer2023\Applications\BasebandFiles\', file_name);
            
            %Makes reader object
            reader = comm.BasebandFileReader(file_path, SamplesPerFrame=inf);
            
            data = reader(); %Gets data
            data = editData(data); %Makes data digital
            time_scope(data);%Plots data
            
            %Updates UI
            app.ReadFileButton.Text = 'Read File';
            app.ReadFileButton.FontColor = [0,0,0];
            app.ReadFileButton.Enable = true;
            app.Status.Text = 'Not Receiving';
            app.Status.FontColor = [1,0,0];
            drawnow();

            %Releases
            release(time_scope);
            release(reader);
        end

        %Decodes the card data in a baseband file
        function decodeBaseband(app)
            %Updates UI to default
            app.CardErrorLabel.Visible = false;
            app.DecodeFileDataButton.Enable = false;
            app.DecodeFileDataButton.Text = 'Decoding Data...';
            app.DecodeFileDataButton.FontColor = [0.13,0.86,0.14];
            app.Status.Text = "Decoding Data...";
            app.Status.FontColor = [0,1,0];
            app.FLAGSLabel.Text = 'FLAGS:';
            app.INFOFLAGSLabel.Text = 'INFO FLAGS:';
            app.UIDLabel.Text = 'UID:';
            app.DSFIDLabel.Text = 'DSFID:';
            app.AFILabel.Text = 'AFI:';
            app.MEMORYLabel.Text = 'MEMORY:';
            app.ICREFERENCELabel.Text = 'IC REFERENCE:';
            app.CRCLabel.Text = 'CRC:';
            drawnow();
            
            %Gets path of selected file
            file_name = app.SelectFileDropDown.Value;
            file_path = append('C:\Users\akamrath2\Documents\USRP_Research_Summer2023\Applications\BasebandFiles\', file_name);

            reader = comm.BasebandFileReader(file_path, SamplesPerFrame=inf); %Creates reader object

            data = reader(); %Gets data
            data = editData(data); %Makes data digital
            
            try
                binary_sequence = decodeCardData(data); %decodes the card data
            catch
                app.CardErrorLabel.Visible = true;
                return;
            end
            %Sorts the binary sequence and displays fields
            [flags, info_flags, uid, dsfid, afi, memory, ic_reference, crc] = sortBinarySequence(binary_sequence);
            app.FLAGSLabel.Text = flags;
            app.INFOFLAGSLabel.Text = info_flags;
            app.UIDLabel.Text = uid;
            app.DSFIDLabel.Text = dsfid;
            app.AFILabel.Text = afi;
            app.MEMORYLabel.Text = memory;
            app.ICREFERENCELabel.Text = ic_reference;
            app.CRCLabel.Text = crc;
            
            %Updates UI
            app.CardErrorLabel.Visible = false;
            app.DecodeFileDataButton.Enable = true;
            app.DecodeFileDataButton.Text = 'Decode File Data';
            app.DecodeFileDataButton.FontColor = [0,0,0];
            app.Status.Text = "Not Receiving";
            app.Status.FontColor = [1,0,0];
            drawnow();

            %Release
            release(reader);
        end

        function decodeRealtime(app)
            %Gets global variables
            global rx;
            global time_scope;
            sampling_rate = app.SamplingRate.Value;

            %Releases global variables
            release(time_scope);
            release(rx);

            %Updates time scope
            time_scope.TimeSpanSource = 'property';
            show(time_scope);

            %Updates UI
            app.CardErrorLabel.Visible = false;
            app.DecodeRealtimeButton.Enable = false;
            app.DecodeRealtimeButton.Text = 'Receiving...';
            app.DecodeRealtimeButton.FontColor = [0,1,0];
            app.Status.Text = "Receiving...";
            app.Status.FontColor = [0,1,0];
            app.FLAGSLabel.Text = 'FLAGS:';
            app.INFOFLAGSLabel.Text = 'INFO FLAGS:';
            app.UIDLabel.Text = 'UID:';
            app.DSFIDLabel.Text = 'DSFID:';
            app.AFILabel.Text = 'AFI:';
            app.MEMORYLabel.Text = 'MEMORY:';
            app.ICREFERENCELabel.Text = 'IC REFERENCE:';
            app.CRCLabel.Text = 'CRC:';
            drawnow();

            %Starting parameter for the loop
            frame_duration = (rx.SamplesPerFrame)/(sampling_rate);
            time = 0;
            total_data = [];
            %Saves data for selected number of seconds
            while time < app.RealtimeSeconds.Value
                data = rx();
                total_data = cat(1,total_data,data);
                time_scope(data);
                time = time + frame_duration;
            end

            %Updates UI
            app.Status.Text = "Formatting Data...";
            app.Status.FontColor = [1,1,0];
            app.DecodeRealtimeButton.Text = 'Formatting Data...';
            app.DecodeRealtimeButton.FontColor = [1,1,0];
            hide(time_scope);
            drawnow();

            total_data = editData(total_data); %Makes data digital

            %Updates UI
            app.Status.Text = "Decoding Data...";
            app.DecodeRealtimeButton.Text = 'Decoding Data...';
            drawnow();

            try
                binary_sequence = decodeCardData(total_data); %decodes the card data
            catch
                app.CardErrorLabel.Visible = true;
                return;
            end

            %Sorts binary sequence and displays fields
            [flags, info_flags, uid, dsfid, afi, memory, ic_reference, crc] = sortBinarySequence(binary_sequence);
            app.FLAGSLabel.Text = flags;
            app.INFOFLAGSLabel.Text = info_flags;
            app.UIDLabel.Text = uid;
            app.DSFIDLabel.Text = dsfid;
            app.AFILabel.Text = afi;
            app.MEMORYLabel.Text = memory;
            app.ICREFERENCELabel.Text = ic_reference;
            app.CRCLabel.Text = crc;
            app.DecodeRealtimeButton.Enable = true;
            app.CardErrorLabel.Visible = false;
            drawnow();
        end

        %Creates the time scope object
        function time_scope = createTimeScope(app)
            %Parameters
            sampling_rate = app.SamplingRate.Value;

            %Creates Time Scope
            time_scope = timescope(TimeSpanSource = "auto",...
                TimeSpan = 1,SampleRate = sampling_rate, ...
                Position=[20,200,800,500], ...
                YLimits=[-.6,.6]);

            hide(time_scope);
            release(time_scope);
        end

        %Populates the baseband files dropdown menu
        function populateFiles(app)
            app.SelectFileDropDown.Items={}; %Clears dropdown
            files = dir("BasebandFiles\");
            for k=1:length(files)
                file_name = files(k).name;
                %Makes sure . and .. are not added to dropdown
                if ~strcmp(file_name, '..') && ~strcmp(file_name, '.')
                    app.SelectFileDropDown.Items = [app.SelectFileDropDown.Items, file_name];
                end
            end
        end
        
        
        function disableButtons(app)
            app.DecodeFileDataButton.Enable = false;
            app.ReadFileButton.Enable = false;
            app.DecodeRealtimeButton.Enable = false;
            app.WriterButton.Enable = false;
            app.RetryConnectionButton.Enable = false;
            app.RealtimeButton.Enable = false;
            drawnow();
        end
        
        function enableButtons(app)
            app.DecodeFileDataButton.Enable = true;
            app.ReadFileButton.Enable = true;
            app.RetryConnectionButton.Enable = true;
            if (app.ConnectingStatus.Text == "Connection Established")
                app.DecodeRealtimeButton.Enable = true;
                app.WriterButton.Enable = true;
                app.RealtimeButton.Enable = true;
            end
            drawnow();
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global rx;
            global time_scope;
            populateFiles(app);
            rx = establishConnection(app);
            time_scope = createTimeScope(app);

        end

        % Value changed function: RealtimeButton
        function RealtimeButtonValueChanged(app, event)
            value = app.RealtimeButton.Value;
            if value == true
                %Updates UI
                app.RealtimeButton.Text = 'Starting Reception...';
                app.RealtimeButton.FontColor = [1,1,0];
                app.Status.Text = 'Starting Reception...';
                app.Status.FontColor = [1,1,0];
                drawnow();
                %Starts realtime reception
                realtimeReception(app);
            else
                %Resets UI
                app.RealtimeButton.Text = "Start Realtime Reception";
                app.RealtimeButton.FontColor = [0,0,0];
                app.Status.Text = "Not Receiving";
                app.Status.FontColor = [1,0,0];
                enableButtons(app);
                drawnow();
            end
        end

        % Button pushed function: RetryConnectionButton
        function RetryConnectionButtonPushed(app, event)
            app.RetryConnectionButton.Enable = false;
            establishConnection(app);
        end

        % Button pushed function: WriterButton
        function WriterButtonPushed(app, event)
            %Updates UI
            app.WriterButton.Text = 'Starting Reception...';
            app.WriterButton.FontColor = [1,1,0];
            app.Status.Text = 'Starting Reception...';
            app.Status.FontColor = [1,1,0];
            disableButtons(app);
            drawnow();

            %Starts reception
            writerReception(app);

            %Updates UI
            app.WriterButton.Text = "Start File Writer Reception";
            app.WriterButton.FontColor = [0,0,0];
            app.WriterButton.Enable = true;
            app.Status.Text = "Not Receiving";
            app.Status.FontColor = [1,0,0];
            populateFiles(app);
            enableButtons(app);
            drawnow();

        end

        % Button pushed function: ReadFileButton
        function ReadFileButtonPushed(app, event)
            disableButtons(app);
            readFile(app);
            enableButtons(app);
        end

        % Button pushed function: DecodeFileDataButton
        function DecodeFileDataButtonPushed(app, event)
            disableButtons(app);
            decodeBaseband(app);
            enableButtons(app);
        end

        % Button pushed function: DecodeRealtimeButton
        function DecodeRealtimeButtonPushed(app, event)
            disableButtons(app);
            decodeRealtime(app);

            %Updates UI
            app.Status.Text = "Not Receiving";
            app.Status.FontColor = [1,0,0];
            app.DecodeRealtimeButton.Text = 'Decode Realtime';
            app.DecodeRealtimeButton.FontColor = [0,0,0];
            enableButtons(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RealtimeButton = uibutton(app.UIFigure, 'state');
            app.RealtimeButton.ValueChangedFcn = createCallbackFcn(app, @RealtimeButtonValueChanged, true);
            app.RealtimeButton.Enable = 'off';
            app.RealtimeButton.Text = 'Start Realtime Reception';
            app.ConnectionStatusLabel = uilabel(app.UIFigure);
            app.ConnectionStatusLabel.Text = 'Connection Status: ';
            app.ConnectingStatus = uilabel(app.UIFigure);
            app.ConnectingStatus.Visible = 'off';
            app.ConnectingStatus.Text = 'Connecting...';
            app.RetryConnectionButton = uibutton(app.UIFigure, 'push');
            app.RetryConnectionButton.ButtonPushedFcn = createCallbackFcn(app, @RetryConnectionButtonPushed, true);
            app.RetryConnectionButton.Enable = 'off';
            app.RetryConnectionButton.Text = 'Retry Connection';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Status:';
            app.Status = uilabel(app.UIFigure);
            app.Status.Text = 'Not Receiving';
            app.EnterFileNameLabel = uilabel(app.UIFigure);
            app.EnterFileNameLabel.Text = 'Enter File Name:';
            app.FileName = uieditfield(app.UIFigure, 'text');
            app.EnterSecondsLabel = uilabel(app.UIFigure);
            app.EnterSecondsLabel.Text = 'Enter Seconds:';
            app.SecondsField = uieditfield(app.UIFigure, 'numeric');
            app.SecondsField.Limits = [1 Inf];
            app.SecondsField.Value = 5;
            app.WriterButton = uibutton(app.UIFigure, 'push');
            app.WriterButton.ButtonPushedFcn = createCallbackFcn(app, @WriterButtonPushed, true);
            app.WriterButton.Enable = 'off';
            app.WriterButton.Text = 'Start File Writer Reception';
            app.ReadFileButton = uibutton(app.UIFigure, 'push');
            app.ReadFileButton.ButtonPushedFcn = createCallbackFcn(app, @ReadFileButtonPushed, true);
            app.ReadFileButton.Text = 'Read File';
            app.SelectFileLabel = uilabel(app.UIFigure);
            app.SelectFileLabel.Text = 'Select File: ';
            app.SelectFileDropDown = uidropdown(app.UIFigure);
            app.SelectFileDropDown.Items = {};
            app.SelectFileDropDown.Value = {};
            app.DecodeFileDataButton = uibutton(app.UIFigure, 'push');
            app.DecodeFileDataButton.ButtonPushedFcn = createCallbackFcn(app, @DecodeFileDataButtonPushed, true);
            app.DecodeFileDataButton.Text = 'Decode File Data';
            app.FLAGSLabel = uilabel(app.UIFigure);
            app.FLAGSLabel.Text = 'FLAGS:';
            app.INFOFLAGSLabel = uilabel(app.UIFigure);
            app.INFOFLAGSLabel.Text = 'INFO FLAGS:';
            app.UIDLabel = uilabel(app.UIFigure);
            app.UIDLabel.Text = 'UID:';
            app.DSFIDLabel = uilabel(app.UIFigure);
            app.DSFIDLabel.Text = 'DSFID:';
            app.AFILabel = uilabel(app.UIFigure);
            app.AFILabel.Text = 'AFI:';
            app.MEMORYLabel = uilabel(app.UIFigure);
            app.MEMORYLabel.Text = 'MEMORY';
            app.ICREFERENCELabel = uilabel(app.UIFigure);
            app.ICREFERENCELabel.Text = 'IC REFERENCE:';
            app.CRCLabel = uilabel(app.UIFigure);
            app.CRCLabel.Text = 'CRC:';
            app.DecodeRealtimeButton = uibutton(app.UIFigure, 'push');
            app.DecodeRealtimeButton.ButtonPushedFcn = createCallbackFcn(app, @DecodeRealtimeButtonPushed, true);
            app.DecodeRealtimeButton.Enable = 'off';
            app.DecodeRealtimeButton.Text = 'Decode Realtime';
            app.EnterSecondsLabel_2 = uilabel(app.UIFigure);
            app.EnterSecondsLabel_2.Text = 'Enter Seconds:';
            app.RealtimeSeconds = uieditfield(app.UIFigure, 'numeric');
            app.RealtimeSeconds.Limits = [1 Inf];
            app.RealtimeSeconds.Value = 5;
            app.SamplingRateEditFieldLabel = uilabel(app.UIFigure);
            app.SamplingRateEditFieldLabel.Text = 'Sampling Rate: ';
            app.SamplingRate = uieditfield(app.UIFigure, 'numeric');
            app.SamplingRate.Value = 2000000;
            app.CardErrorLabel = uilabel(app.UIFigure);
            app.CardErrorLabel.Visible = 'off';
            app.CardErrorLabel.Text = 'CARD NOT FOUND';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Receiver

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end