classdef Transmitter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        RFFieldButton  matlab.ui.control.StateButton
        Status         matlab.ui.control.Label
        StatusLabel    matlab.ui.control.Label
    end


    methods (Access = private)

        function startRF(app)
            global rf_field;
            global tx;
            
            while app.RFFieldButton.Value == true
                tx(rf_field);
                drawnow();
            end
            release(tx);
        end

        function stopRF(app)

        end

        function tx = createTransmitter(app)
            center_frequency = 13560000;
            sampling_rate = 2000000;
            interpolation_factor = round(100000000/sampling_rate);

            tx = comm.SDRuTransmitter(...
                Platform = "N200/N210/USRP2", ...
                IPAddress = '192.168.10.3', ...
                MasterClockRate = 100e6, ...
                InterpolationFactor = interpolation_factor, ...
                Gain = 0, ...
                CenterFrequency = center_frequency, ...
                TransportDataType = "int16");
        end

        function rf_field = createRFfield(app)
            sinewave = dsp.SineWave(...
                Amplitude=5, ...
                Frequency=140.5, ...
                SampleRate=2000000, ...
                SamplesPerFrame=50000, ...
                ComplexOutput=true);
            rf_field = step(sinewave);
            release(sinewave);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global tx;
            global rf_field;
            tx = createTransmitter(app);
            rf_field = createRFfield(app);
        end

        % Value changed function: RFFieldButton
        function RFFieldButtonValueChanged(app, event)
            value = app.RFFieldButton.Value;
            if value == 1
                %Update UI
                app.Status.Text = 'RF Field On';
                app.Status.FontColor = [0,1,0];
                drawnow()

                startRF(app);
            else
                %Update UI
                app.Status.Text = 'RF Field Off';
                app.Status.FontColor = [1,0,0];
                drawnow();

                stopRF(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Status: ';
            app.Status = uilabel(app.UIFigure);
            app.Status.Text = 'RF Field Off';
            app.RFFieldButton = uibutton(app.UIFigure, 'state');
            app.RFFieldButton.ValueChangedFcn = createCallbackFcn(app, @RFFieldButtonValueChanged, true);
            app.RFFieldButton.Text = 'RF Field';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Transmitter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end